classdef Instructions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMRInstructionsUIFigure  matlab.ui.Figure
        InstructionsLabel        matlab.ui.control.Label
        InLabel                  matlab.ui.control.Label
        OKButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.OMRInstructionsUIFigure,"center");
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Hide Media')
                app.HM_label.Visible = true;
                app.MD_label.Visible = false;
            else
                app.HM_label.Visible = false;
                app.MD_label.Visible = true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMRInstructionsUIFigure = uifigure('Visible', 'off');
            app.OMRInstructionsUIFigure.Name = 'Instructions';
            app.InstructionsLabel = uilabel(app.OMRInstructionsUIFigure);
            app.InstructionsLabel.Text = 'Instructions';
            app.InLabel = uilabel(app.OMRInstructionsUIFigure);
            app.InLabel.Text = {'1. Click on "Browse" to select image(s) to be '; '   read.'; '2. To generate the header files for audio '; '   replay, click on "Read Score".'; '3. Click on "Export Data" to save the current'; '   status report (if desired).'; '4. To process another image set, click Reset.'; ''; 'Note: After reading the inputs, you can display'; '      the previous scanned sections by using '; '      the "Section Navigation" buttons below.'};
            app.OKButton = uibutton(app.OMRInstructionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.OMRInstructionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Instructions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OMRInstructionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMRInstructionsUIFigure)
        end
    end
end